classdef Dimensionnement_moteur < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RmEditField                     matlab.ui.control.EditField
        RmEditFieldLabel                matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        CalculerButton_20               matlab.ui.control.Button
        BdrEditField                    matlab.ui.control.EditField
        BdrEditFieldLabel               matlab.ui.control.Label
        BcrEditField                    matlab.ui.control.EditField
        BcrEditFieldLabel               matlab.ui.control.Label
        TLabel_5                        matlab.ui.control.Label
        TLabel_4                        matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        SauvegarderButton               matlab.ui.control.Button
        ChargerButton                   matlab.ui.control.Button
        RadLabel                        matlab.ui.control.Label
        Puissance_mecaEditField         matlab.ui.control.EditField
        Puissance_mecaEditFieldLabel    matlab.ui.control.Label
        CosPhiEditField                 matlab.ui.control.EditField
        CosPhiEditFieldLabel            matlab.ui.control.Label
        RendementEditField              matlab.ui.control.EditField
        RendementEditFieldLabel         matlab.ui.control.Label
        CoupleEditField                 matlab.ui.control.EditField
        CoupleEditFieldLabel            matlab.ui.control.Label
        CalculerButton_19               matlab.ui.control.Button
        WLabel                          matlab.ui.control.Label
        CalculerButton_18               matlab.ui.control.Button
        Label_7                         matlab.ui.control.Label
        CalculerButton_17               matlab.ui.control.Button
        CalculerButton_16               matlab.ui.control.Button
        NmLabel                         matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        HcrEditField                    matlab.ui.control.EditField
        HcrEditFieldLabel               matlab.ui.control.Label
        HdrEditField                    matlab.ui.control.EditField
        HdrEditFieldLabel               matlab.ui.control.Label
        ATmLabel_4                      matlab.ui.control.Label
        ATmLabel_3                      matlab.ui.control.Label
        HcsEditField                    matlab.ui.control.EditField
        HcsEditFieldLabel               matlab.ui.control.Label
        HdsEditField                    matlab.ui.control.EditField
        HdsEditFieldLabel               matlab.ui.control.Label
        ATmLabel_2                      matlab.ui.control.Label
        ATmLabel                        matlab.ui.control.Label
        BcsEditField                    matlab.ui.control.EditField
        BcsEditFieldLabel               matlab.ui.control.Label
        TLabel_3                        matlab.ui.control.Label
        BdsEditField                    matlab.ui.control.EditField
        BdsEditFieldLabel               matlab.ui.control.Label
        TLabel_2                        matlab.ui.control.Label
        EffacerButton                   matlab.ui.control.Button
        TracerButton                    matlab.ui.control.Button
        ImEditField                     matlab.ui.control.EditField
        ImEditFieldLabel                matlab.ui.control.Label
        ALabel_2                        matlab.ui.control.Label
        CalculerButton_15               matlab.ui.control.Button
        IsEditField                     matlab.ui.control.EditField
        IsEditFieldLabel                matlab.ui.control.Label
        CalculerButton_13               matlab.ui.control.Button
        ALabel                          matlab.ui.control.Label
        Vs_effEditField                 matlab.ui.control.EditField
        Vs_effEditFieldLabel            matlab.ui.control.Label
        CalculerButton_12               matlab.ui.control.Button
        VLabel_2                        matlab.ui.control.Label
        VLabel                          matlab.ui.control.Label
        EEditField                      matlab.ui.control.EditField
        EEditFieldLabel                 matlab.ui.control.Label
        CalculerButton_10               matlab.ui.control.Button
        GlissementEditField             matlab.ui.control.NumericEditField
        GlissementEditFieldLabel        matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ErreursEditField                matlab.ui.control.EditField
        ErreursEditFieldLabel           matlab.ui.control.Label
        WLabel_2                        matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        mLabel_15                       matlab.ui.control.Label
        mLabel_14                       matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        TLabel                          matlab.ui.control.Label
        mLabel_13                       matlab.ui.control.Label
        mLabel_12                       matlab.ui.control.Label
        mLabel_11                       matlab.ui.control.Label
        mLabel_10                       matlab.ui.control.Label
        mLabel_9                        matlab.ui.control.Label
        mLabel_8                        matlab.ui.control.Label
        mLabel_7                        matlab.ui.control.Label
        mLabel_6                        matlab.ui.control.Label
        mLabel_5                        matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        KLabel_2                        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        KLabel                          matlab.ui.control.Label
        Pertes_ferEditField             matlab.ui.control.EditField
        Pertes_ferEditFieldLabel        matlab.ui.control.Label
        Largeur_encoche_rEditField      matlab.ui.control.NumericEditField
        Largeur_encoche_rEditFieldLabel  matlab.ui.control.Label
        Largeur_encoche_sEditField      matlab.ui.control.NumericEditField
        Largeur_encoche_sEditFieldLabel  matlab.ui.control.Label
        XrEditField                     matlab.ui.control.EditField
        XrEditFieldLabel                matlab.ui.control.Label
        XsEditField                     matlab.ui.control.EditField
        XsEditFieldLabel                matlab.ui.control.Label
        XmEditField                     matlab.ui.control.EditField
        XmEditFieldLabel                matlab.ui.control.Label
        RrEditField                     matlab.ui.control.EditField
        RrEditFieldLabel                matlab.ui.control.Label
        hauteur_tete_bobine_rEditField  matlab.ui.control.NumericEditField
        hauteur_tete_bobine_rEditFieldLabel  matlab.ui.control.Label
        N_bobines_par_encoches_rEditField  matlab.ui.control.NumericEditField
        N_bobines_par_encoches_rEditFieldLabel  matlab.ui.control.Label
        Longueur_rotorEditField         matlab.ui.control.NumericEditField
        Longueur_rotorEditFieldLabel    matlab.ui.control.Label
        Diametre_externe_rEditField     matlab.ui.control.NumericEditField
        Diametre_externe_rEditFieldLabel  matlab.ui.control.Label
        q_rNbdencochespolephaseEditField  matlab.ui.control.NumericEditField
        q_rNbdencochespolephaseEditFieldLabel  matlab.ui.control.Label
        Nombre_de_tours_par_bobines_rEditField  matlab.ui.control.NumericEditField
        Nombre_de_tours_par_bobines_rEditFieldLabel  matlab.ui.control.Label
        Nombre_paires_de_poles_rEditField  matlab.ui.control.NumericEditField
        Nombre_paires_de_poles_rEditFieldLabel  matlab.ui.control.Label
        Section_fil_rEditField          matlab.ui.control.NumericEditField
        Section_fil_rEditFieldLabel     matlab.ui.control.Label
        Temperature_enroulement_rEditField  matlab.ui.control.NumericEditField
        Temperature_enroulement_rEditFieldLabel  matlab.ui.control.Label
        RsEditField                     matlab.ui.control.EditField
        RsEditFieldLabel                matlab.ui.control.Label
        hauteur_tete_bobineEditField    matlab.ui.control.NumericEditField
        hauteur_tete_bobineEditFieldLabel  matlab.ui.control.Label
        N_bobines_par_encochesEditField  matlab.ui.control.NumericEditField
        N_bobines_par_encochesEditFieldLabel  matlab.ui.control.Label
        Profondeur_moteurEditField      matlab.ui.control.NumericEditField
        Profondeur_moteurEditFieldLabel  matlab.ui.control.Label
        Diametre_interne_sEditField     matlab.ui.control.NumericEditField
        Diametre_interne_sEditFieldLabel  matlab.ui.control.Label
        qNbdencochespolephaseEditField  matlab.ui.control.NumericEditField
        qNbdencochespolephaseLabel      matlab.ui.control.Label
        Nombre_de_tours_par_bobines_sEditField  matlab.ui.control.NumericEditField
        Nombre_de_tours_par_bobines_sEditFieldLabel  matlab.ui.control.Label
        Nombre_paires_de_poles_sEditField  matlab.ui.control.NumericEditField
        Nombre_paires_de_poles_sEditFieldLabel  matlab.ui.control.Label
        Section_fil_sEditField          matlab.ui.control.NumericEditField
        Section_fil_sEditFieldLabel     matlab.ui.control.Label
        Temperature_enroulement_sEditField  matlab.ui.control.NumericEditField
        Temperature_enroulement_sEditFieldLabel  matlab.ui.control.Label
        Longueur_dent_rotorEditField    matlab.ui.control.NumericEditField
        Longueur_dent_rotorEditFieldLabel  matlab.ui.control.Label
        Largeur_dent_statorEditField    matlab.ui.control.NumericEditField
        Largeur_dent_statorEditFieldLabel  matlab.ui.control.Label
        Longueur_dent_statorEditField   matlab.ui.control.NumericEditField
        Longueur_dent_statorEditFieldLabel  matlab.ui.control.Label
        Largeur_dent_rotorEditField     matlab.ui.control.NumericEditField
        Largeur_dent_rotorEditFieldLabel  matlab.ui.control.Label
        Rayon_machineEditField          matlab.ui.control.NumericEditField
        Rayon_machineEditFieldLabel     matlab.ui.control.Label
        BeEditField                     matlab.ui.control.NumericEditField
        BeEditFieldLabel                matlab.ui.control.Label
        fEditField                      matlab.ui.control.NumericEditField
        fEditFieldLabel                 matlab.ui.control.Label
        angle_de_racourcissementEditField  matlab.ui.control.NumericEditField
        angle_de_racourcissementEditFieldLabel  matlab.ui.control.Label
        Nombre_phasesEditField          matlab.ui.control.NumericEditField
        Nombre_phasesEditFieldLabel     matlab.ui.control.Label
        ToutcalculerButton              matlab.ui.control.Button
        CalculerButton_7                matlab.ui.control.Button
        CalculerButton_5                matlab.ui.control.Button
        CalculerButton_4                matlab.ui.control.Button
        CalculerButton_3                matlab.ui.control.Button
        CalculerButton_2                matlab.ui.control.Button
        CalculerButton                  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculerButton
        function CalculerButtonPushed(app, event)
            num1 = app.Temperature_enroulement_sEditField.Value;
            num2 = app.Section_fil_sEditField.Value;
            num3 = app.Nombre_paires_de_poles_sEditField.Value;
            num4 = app.Nombre_de_tours_par_bobines_sEditField.Value;
            num5 = app.qNbdencochespolephaseEditField.Value;
            num6 = app.hauteur_tete_bobineEditField.Value;
            num7=app.Diametre_interne_sEditField.Value;
            num8=app.Profondeur_moteurEditField.Value;
            num9=app.N_bobines_par_encochesEditField.Value;
            u=[num1,num2,num3,num4,num5,num6,num7,num8,num9];
             
            result = Rs(u); % Call the external function

            formattedResult = sprintf('%.4e', result); % On force la notation scientifique avec décimales après la virgule

            app.RsEditField.Value = formattedResult;% Afficher le résultat

        end

        % Button pushed function: CalculerButton_2
        function CalculerButton_2Pushed(app, event)
            num1 = app.Temperature_enroulement_sEditField.Value;
            num2 = app.Section_fil_sEditField.Value;
            num3 = app.Nombre_paires_de_poles_sEditField.Value;
            num4 = app.Nombre_de_tours_par_bobines_sEditField.Value;
            num5 = app.qNbdencochespolephaseEditField.Value;
            num6 = app.hauteur_tete_bobineEditField.Value;
            num7=app.Diametre_interne_sEditField.Value;
            num8=app.Profondeur_moteurEditField.Value;
            num9=app.N_bobines_par_encochesEditField.Value;

            num10=app.Temperature_enroulement_rEditField.Value;
            num11=app.Section_fil_rEditField.Value;
            num12=app.Nombre_paires_de_poles_rEditField.Value;
            num13=app.Nombre_de_tours_par_bobines_rEditField.Value;
            num14=app.q_rNbdencochespolephaseEditField.Value;
            num15=app.hauteur_tete_bobine_rEditField.Value;
            num16=app.Diametre_externe_rEditField.Value;
            num17=app.Longueur_rotorEditField.Value;
            num18=app.N_bobines_par_encoches_rEditField.Value;
            u=[num1,num2,num3,num4,num5,num6,num7,num8,num9,num10,num11,num12,num13,num14,num15,num16,num17,num18];
             % Call the external multiplication function
            result = Rr(u);
            % Display the result
            %app.RsEditField.Value = ['Result: ' num2str(double(result))];
            %app.RsEditField.Value = ['Result: ' num2str(result)];
            formattedResult = sprintf('%.4e', result);

        % Afficher le résultat
        app.RrEditField.Value = formattedResult;
        end

        % Button pushed function: CalculerButton_3
        function CalculerButton_3Pushed(app, event)
            num1 = app.Temperature_enroulement_sEditField.Value;
            num2 = app.Section_fil_sEditField.Value;
            num3 = app.Nombre_paires_de_poles_sEditField.Value;
            num4 = app.Nombre_de_tours_par_bobines_sEditField.Value;
            num5 = app.qNbdencochespolephaseEditField.Value;
            num6 = app.hauteur_tete_bobineEditField.Value;
            num7=app.Diametre_interne_sEditField.Value;
            num8=app.Profondeur_moteurEditField.Value;
            num9=app.N_bobines_par_encochesEditField.Value;

            num10=app.Temperature_enroulement_rEditField.Value;
            num11=app.Section_fil_rEditField.Value;
            num12=app.Nombre_paires_de_poles_rEditField.Value;
            num13=app.Nombre_de_tours_par_bobines_rEditField.Value;
            num14=app.q_rNbdencochespolephaseEditField.Value;
            num15=app.hauteur_tete_bobine_rEditField.Value;
            num16=app.Diametre_externe_rEditField.Value;
            num17=app.Longueur_rotorEditField.Value;
            num18=app.N_bobines_par_encoches_rEditField.Value;
            num19=app.Nombre_phasesEditField.Value;
            num20=app.angle_de_racourcissementEditField.Value;
            num21=app.fEditField.Value;
            num22=app.BeEditField.Value;
            num23=app.Rayon_machineEditField.Value;
            num24=app.Longueur_dent_rotorEditField.Value;
            num25=app.Largeur_dent_rotorEditField.Value;
            num26=app.Longueur_dent_statorEditField.Value;
            num27=app.Largeur_dent_statorEditField.Value;
            u=[num1,num2,num3,num4,num5,num6,num7,num8,num9,num10,num11,num12,num13,num14,num15,num16,num17,num18,num19,num20,num21,num22,num23,num24,num25,num26,num27];
             % Call the external multiplication function
            result = Xm(u);
            % Display the result
            %app.RsEditField.Value = ['Result: ' num2str(double(result))];
            %app.RsEditField.Value = ['Result: ' num2str(result)];
            formattedResult = sprintf('%.4e', result);

        % Afficher le résultat
        app.XmEditField.Value = formattedResult;

        end

        % Button pushed function: CalculerButton_4
        function CalculerButton_4Pushed(app, event)
                        num1 = app.Temperature_enroulement_sEditField.Value;
            num2 = app.Section_fil_sEditField.Value;
            num3 = app.Nombre_paires_de_poles_sEditField.Value;
            num4 = app.Nombre_de_tours_par_bobines_sEditField.Value;
            num5 = app.qNbdencochespolephaseEditField.Value;
            num6 = app.hauteur_tete_bobineEditField.Value;
            num7=app.Diametre_interne_sEditField.Value;
            num8=app.Profondeur_moteurEditField.Value;
            num9=app.N_bobines_par_encochesEditField.Value;

            num10=app.Temperature_enroulement_rEditField.Value;
            num11=app.Section_fil_rEditField.Value;
            num12=app.Nombre_paires_de_poles_rEditField.Value;
            num13=app.Nombre_de_tours_par_bobines_rEditField.Value;
            num14=app.q_rNbdencochespolephaseEditField.Value;
            num15=app.hauteur_tete_bobine_rEditField.Value;
            num16=app.Diametre_externe_rEditField.Value;
            num17=app.Longueur_rotorEditField.Value;
            num18=app.N_bobines_par_encoches_rEditField.Value;
            num19=app.Nombre_phasesEditField.Value;
            num20=app.angle_de_racourcissementEditField.Value;
            num21=app.fEditField.Value;
            num22=app.BeEditField.Value;
            num23=app.Rayon_machineEditField.Value;
            num24=app.Longueur_dent_rotorEditField.Value;
            num25=app.Largeur_dent_rotorEditField.Value;
            num26=app.Longueur_dent_statorEditField.Value;
            num27=app.Largeur_dent_statorEditField.Value;
num28=app.Largeur_encoche_sEditField.Value;
            u=[num1,num2,num3,num4,num5,num6,num7,num8,num9,num10,num11,num12,num13,num14,num15,num16,num17,num18,num19,num20,num21,num22,num23,num24,num25,num26,num27,num28];
             % Call the external multiplication function
            result = Xs(u);
            % Display the result
            %app.RsEditField.Value = ['Result: ' num2str(double(result))];
            %app.RsEditField.Value = ['Result: ' num2str(result)];
            formattedResult = sprintf('%.4e', result);

        % Afficher le résultat
        app.XsEditField.Value = formattedResult;
        end

        % Button pushed function: CalculerButton_5
        function CalculerButton_5Pushed(app, event)
            num1 = app.Temperature_enroulement_sEditField.Value;
            num2 = app.Section_fil_sEditField.Value;
            num3 = app.Nombre_paires_de_poles_sEditField.Value;
            num4 = app.Nombre_de_tours_par_bobines_sEditField.Value;
            num5 = app.qNbdencochespolephaseEditField.Value;
            num6 = app.hauteur_tete_bobineEditField.Value;
            num7=app.Diametre_interne_sEditField.Value;
            num8=app.Profondeur_moteurEditField.Value;
            num9=app.N_bobines_par_encochesEditField.Value;

            num10=app.Temperature_enroulement_rEditField.Value;
            num11=app.Section_fil_rEditField.Value;
            num12=app.Nombre_paires_de_poles_rEditField.Value;
            num13=app.Nombre_de_tours_par_bobines_rEditField.Value;
            num14=app.q_rNbdencochespolephaseEditField.Value;
            num15=app.hauteur_tete_bobine_rEditField.Value;
            num16=app.Diametre_externe_rEditField.Value;
            num17=app.Longueur_rotorEditField.Value;
            num18=app.N_bobines_par_encoches_rEditField.Value;
            num19=app.Nombre_phasesEditField.Value;
            num20=app.angle_de_racourcissementEditField.Value;
            num21=app.fEditField.Value;
            num22=app.BeEditField.Value;
            num23=app.Rayon_machineEditField.Value;
            num24=app.Longueur_dent_rotorEditField.Value;
            num25=app.Largeur_dent_rotorEditField.Value;
            num26=app.Longueur_dent_statorEditField.Value;
            num27=app.Largeur_dent_statorEditField.Value;
            num28=app.Largeur_encoche_sEditField.Value;
            num29=app.Largeur_encoche_sEditField.Value;
            u=[num1,num2,num3,num4,num5,num6,num7,num8,num9,num10,num11,num12,num13,num14,num15,num16,num17,num18,num19,num20,num21,num22,num23,num24,num25,num26,num27,num28,num29];
             % Call the external multiplication function
            result = Xr(u);
            % Display the result
            %app.RsEditField.Value = ['Result: ' num2str(double(result))];
            %app.RsEditField.Value = ['Result: ' num2str(result)];
            formattedResult = sprintf('%.4e', result);

        % Afficher le résultat
        app.XrEditField.Value = formattedResult;
        end

        % Button pushed function: CalculerButton_7
        function CalculerButton_7Pushed(app, event)
                        num1 = app.Temperature_enroulement_sEditField.Value;
            num2 = app.Section_fil_sEditField.Value;
            num3 = app.Nombre_paires_de_poles_sEditField.Value;
            num4 = app.Nombre_de_tours_par_bobines_sEditField.Value;
            num5 = app.qNbdencochespolephaseEditField.Value;
            num6 = app.hauteur_tete_bobineEditField.Value;
            num7=app.Diametre_interne_sEditField.Value;
            num8=app.Profondeur_moteurEditField.Value;
            num9=app.N_bobines_par_encochesEditField.Value;

            num10=app.Temperature_enroulement_rEditField.Value;
            num11=app.Section_fil_rEditField.Value;
            num12=app.Nombre_paires_de_poles_rEditField.Value;
            num13=app.Nombre_de_tours_par_bobines_rEditField.Value;
            num14=app.q_rNbdencochespolephaseEditField.Value;
            num15=app.hauteur_tete_bobine_rEditField.Value;
            num16=app.Diametre_externe_rEditField.Value;
            num17=app.Longueur_rotorEditField.Value;
            num18=app.N_bobines_par_encoches_rEditField.Value;
            num19=app.Nombre_phasesEditField.Value;
            num20=app.angle_de_racourcissementEditField.Value;
            num21=app.fEditField.Value;
            num22=app.BeEditField.Value;
            num23=app.Rayon_machineEditField.Value;
            num24=app.Longueur_dent_rotorEditField.Value;
            num25=app.Largeur_dent_rotorEditField.Value;
            num26=app.Longueur_dent_statorEditField.Value;
            num27=app.Largeur_dent_statorEditField.Value;
            num28=app.Largeur_encoche_sEditField.Value;
            num29=app.Largeur_encoche_sEditField.Value;
            u=[num1,num2,num3,num4,num5,num6,num7,num8,num9,num10,num11,num12,num13,num14,num15,num16,num17,num18,num19,num20,num21,num22,num23,num24,num25,num26,num27,num28,num29];

            result = Pertes_fer(u);
            formattedResult = sprintf('%.4e', result);
            app.Pertes_ferEditField.Value = formattedResult;
        end

        % Button pushed function: ToutcalculerButton
        function ToutcalculerButtonPushed(app, event)
            num1 = app.Temperature_enroulement_sEditField.Value;
            num2 = app.Section_fil_sEditField.Value;
            num3 = app.Nombre_paires_de_poles_sEditField.Value;
            num4 = app.Nombre_de_tours_par_bobines_sEditField.Value;
            num5 = app.qNbdencochespolephaseEditField.Value;
            num6 = app.hauteur_tete_bobineEditField.Value;
            num7=app.Diametre_interne_sEditField.Value;
            num8=app.Profondeur_moteurEditField.Value;
            num9=app.N_bobines_par_encochesEditField.Value;

            num10=app.Temperature_enroulement_rEditField.Value;
            num11=app.Section_fil_rEditField.Value;
            num12=app.Nombre_paires_de_poles_rEditField.Value;
            num13=app.Nombre_de_tours_par_bobines_rEditField.Value;
            num14=app.q_rNbdencochespolephaseEditField.Value;
            num15=app.hauteur_tete_bobine_rEditField.Value;
            num16=app.Diametre_externe_rEditField.Value;
            num17=app.Longueur_rotorEditField.Value;
            num18=app.N_bobines_par_encoches_rEditField.Value;
            num19=app.Nombre_phasesEditField.Value;
            num20=app.angle_de_racourcissementEditField.Value;
            num21=app.fEditField.Value;
            num22=app.BeEditField.Value;
            num23=app.Rayon_machineEditField.Value;
            num24=app.Longueur_dent_rotorEditField.Value;
            num25=app.Largeur_dent_rotorEditField.Value;
            num26=app.Longueur_dent_statorEditField.Value;
            num27=app.Largeur_dent_statorEditField.Value;
            num28=app.Largeur_encoche_sEditField.Value;
            num29=app.Largeur_encoche_sEditField.Value;
            num30=app.GlissementEditField.Value;
            u=[num1,num2,num3,num4,num5,num6,num7,num8,num9,num10,num11,num12,num13,num14,num15,num16,num17,num18,num19,num20,num21,num22,num23,num24,num25,num26,num27,num28,num29,num30];
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            result = Rs(u);
            formattedResult = sprintf('%.4e', result);
            app.RsEditField.Value = formattedResult;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            result = Rr(u);
            formattedResult = sprintf('%.4e', result);
            app.RrEditField.Value = formattedResult;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            result = Xm(u);
            formattedResult = sprintf('%.4e', result);
            app.XmEditField.Value = formattedResult;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            result = Xs(u);
            formattedResult = sprintf('%.4e', result);
            app.XsEditField.Value = formattedResult;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            result = Xr(u);
            formattedResult = sprintf('%.4e', result);
            app.XrEditField.Value = formattedResult;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            result = Pertes_fer(u);
            formattedResult = sprintf('%.4e', result);
            app.Pertes_ferEditField.Value = formattedResult;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        result = calcul_couple(u);
            formattedResult = sprintf('%.4e', result);
            app.CoupleEditField.Value = formattedResult;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            result = rendement(u);
            formattedResult = sprintf('%.4e', result);
            app.RendementEditField.Value = formattedResult;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            result = E(u);
            formattedResult = sprintf('%.4e', result);
            app.EEditField.Value = formattedResult;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            result = cosphi(u);
            formattedResult = sprintf('%.4e', result);
            app.CosPhiEditField.Value = formattedResult;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            result = abs(Vs(u));
            formattedResult = sprintf('%.4e', result);
            app.Vs_effEditField.Value = formattedResult;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            result = Is(u);
            formattedResult = sprintf('%.4e', result);
            app.IsEditField.Value = formattedResult;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            result = Puissance_meca(u);
            formattedResult = sprintf('%.4e', result);
            app.Puissance_mecaEditField.Value = formattedResult;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            result = Im(u);
            formattedResult = sprintf('%.4e', result);
            app.ImEditField.Value = formattedResult;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            result = Bds(u);
            formattedResult = sprintf('%.4e', result);
            app.BdsEditField.Value = formattedResult;


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            result = Bcs(u);
            formattedResult = sprintf('%.4e', result);
            app.BcsEditField.Value = formattedResult;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            result = Bdr_Bcr_Hdr_Hcr_Hds_Hcs(u);
            formattedResult = sprintf('%.4e', result(1));
            app.BdrEditField.Value = formattedResult;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            result = Bdr_Bcr_Hdr_Hcr_Hds_Hcs(u);
            formattedResult = sprintf('%.4e', result(2));
            app.BcrEditField.Value = formattedResult;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            result = Bdr_Bcr_Hdr_Hcr_Hds_Hcs(u);
            formattedResult = sprintf('%.4e', result(3));
            app.HdrEditField.Value = formattedResult;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
            result = Bdr_Bcr_Hdr_Hcr_Hds_Hcs(u);
            formattedResult = sprintf('%.4e', result(4));
            app.HcrEditField.Value = formattedResult;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                       
            result = Bdr_Bcr_Hdr_Hcr_Hds_Hcs(u);
            formattedResult = sprintf('%.4e', result(5));
            app.HdsEditField.Value = formattedResult;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        
            result = Bdr_Bcr_Hdr_Hcr_Hds_Hcs(u);
            formattedResult = sprintf('%.4e', result(6));
            app.HcsEditField.Value = formattedResult;

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            mat=trace_couple_glissement(u);
            plot(app.UIAxes,mat(1, :), mat(2, :) );

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            result = Rm(u);
            formattedResult = sprintf('%.4e', result);
            app.RmEditField.Value = formattedResult;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.ErreursEditField.Value=strcat(impossibilites(u));
        end

        % Button pushed function: CalculerButton_16
        function CalculerButton_8Pushed(app, event)
                        num1 = app.Temperature_enroulement_sEditField.Value;
            num2 = app.Section_fil_sEditField.Value;
            num3 = app.Nombre_paires_de_poles_sEditField.Value;
            num4 = app.Nombre_de_tours_par_bobines_sEditField.Value;
            num5 = app.qNbdencochespolephaseEditField.Value;
            num6 = app.hauteur_tete_bobineEditField.Value;
            num7=app.Diametre_interne_sEditField.Value;
            num8=app.Profondeur_moteurEditField.Value;
            num9=app.N_bobines_par_encochesEditField.Value;

            num10=app.Temperature_enroulement_rEditField.Value;
            num11=app.Section_fil_rEditField.Value;
            num12=app.Nombre_paires_de_poles_rEditField.Value;
            num13=app.Nombre_de_tours_par_bobines_rEditField.Value;
            num14=app.q_rNbdencochespolephaseEditField.Value;
            num15=app.hauteur_tete_bobine_rEditField.Value;
            num16=app.Diametre_externe_rEditField.Value;
            num17=app.Longueur_rotorEditField.Value;
            num18=app.N_bobines_par_encoches_rEditField.Value;
            num19=app.Nombre_phasesEditField.Value;
            num20=app.angle_de_racourcissementEditField.Value;
            num21=app.fEditField.Value;
            num22=app.BeEditField.Value;
            num23=app.Rayon_machineEditField.Value;
            num24=app.Longueur_dent_rotorEditField.Value;
            num25=app.Largeur_dent_rotorEditField.Value;
            num26=app.Longueur_dent_statorEditField.Value;
            num27=app.Largeur_dent_statorEditField.Value;
            num28=app.Largeur_encoche_sEditField.Value;
            num29=app.Largeur_encoche_sEditField.Value;
            num30=app.GlissementEditField.Value;
            u=[num1,num2,num3,num4,num5,num6,num7,num8,num9,num10,num11,num12,num13,num14,num15,num16,num17,num18,num19,num20,num21,num22,num23,num24,num25,num26,num27,num28,num29,num30];
            
            result = calcul_couple(u);
            formattedResult = sprintf('%.4e', result);
            app.CoupleEditField.Value = formattedResult;
        end

        % Button pushed function: CalculerButton_17
        function CalculerButton_9Pushed(app, event)
            num1 = app.Temperature_enroulement_sEditField.Value;
            num2 = app.Section_fil_sEditField.Value;
            num3 = app.Nombre_paires_de_poles_sEditField.Value;
            num4 = app.Nombre_de_tours_par_bobines_sEditField.Value;
            num5 = app.qNbdencochespolephaseEditField.Value;
            num6 = app.hauteur_tete_bobineEditField.Value;
            num7=app.Diametre_interne_sEditField.Value;
            num8=app.Profondeur_moteurEditField.Value;
            num9=app.N_bobines_par_encochesEditField.Value;

            num10=app.Temperature_enroulement_rEditField.Value;
            num11=app.Section_fil_rEditField.Value;
            num12=app.Nombre_paires_de_poles_rEditField.Value;
            num13=app.Nombre_de_tours_par_bobines_rEditField.Value;
            num14=app.q_rNbdencochespolephaseEditField.Value;
            num15=app.hauteur_tete_bobine_rEditField.Value;
            num16=app.Diametre_externe_rEditField.Value;
            num17=app.Longueur_rotorEditField.Value;
            num18=app.N_bobines_par_encoches_rEditField.Value;
            num19=app.Nombre_phasesEditField.Value;
            num20=app.angle_de_racourcissementEditField.Value;
            num21=app.fEditField.Value;
            num22=app.BeEditField.Value;
            num23=app.Rayon_machineEditField.Value;
            num24=app.Longueur_dent_rotorEditField.Value;
            num25=app.Largeur_dent_rotorEditField.Value;
            num26=app.Longueur_dent_statorEditField.Value;
            num27=app.Largeur_dent_statorEditField.Value;
            num28=app.Largeur_encoche_sEditField.Value;
            num29=app.Largeur_encoche_sEditField.Value;
            num30=app.GlissementEditField.Value;
            u=[num1,num2,num3,num4,num5,num6,num7,num8,num9,num10,num11,num12,num13,num14,num15,num16,num17,num18,num19,num20,num21,num22,num23,num24,num25,num26,num27,num28,num29,num30];
                        result = rendement(u);
            formattedResult = sprintf('%.4e', result);
            app.RendementEditField.Value = formattedResult;
        end

        % Button pushed function: CalculerButton_10
        function CalculerButton_10Pushed(app, event)
            num1 = app.Temperature_enroulement_sEditField.Value;
            num2 = app.Section_fil_sEditField.Value;
            num3 = app.Nombre_paires_de_poles_sEditField.Value;
            num4 = app.Nombre_de_tours_par_bobines_sEditField.Value;
            num5 = app.qNbdencochespolephaseEditField.Value;
            num6 = app.hauteur_tete_bobineEditField.Value;
            num7=app.Diametre_interne_sEditField.Value;
            num8=app.Profondeur_moteurEditField.Value;
            num9=app.N_bobines_par_encochesEditField.Value;

            num10=app.Temperature_enroulement_rEditField.Value;
            num11=app.Section_fil_rEditField.Value;
            num12=app.Nombre_paires_de_poles_rEditField.Value;
            num13=app.Nombre_de_tours_par_bobines_rEditField.Value;
            num14=app.q_rNbdencochespolephaseEditField.Value;
            num15=app.hauteur_tete_bobine_rEditField.Value;
            num16=app.Diametre_externe_rEditField.Value;
            num17=app.Longueur_rotorEditField.Value;
            num18=app.N_bobines_par_encoches_rEditField.Value;
            num19=app.Nombre_phasesEditField.Value;
            num20=app.angle_de_racourcissementEditField.Value;
            num21=app.fEditField.Value;
            num22=app.BeEditField.Value;
            num23=app.Rayon_machineEditField.Value;
            num24=app.Longueur_dent_rotorEditField.Value;
            num25=app.Largeur_dent_rotorEditField.Value;
            num26=app.Longueur_dent_statorEditField.Value;
            num27=app.Largeur_dent_statorEditField.Value;
            num28=app.Largeur_encoche_sEditField.Value;
            num29=app.Largeur_encoche_sEditField.Value;
            num30=app.GlissementEditField.Value;
            u=[num1,num2,num3,num4,num5,num6,num7,num8,num9,num10,num11,num12,num13,num14,num15,num16,num17,num18,num19,num20,num21,num22,num23,num24,num25,num26,num27,num28,num29,num30];
                        result = E(u);
            formattedResult = sprintf('%.4e', result);
            app.EEditField.Value = formattedResult;
        end

        % Button pushed function: CalculerButton_18
        function CalculerButton_11Pushed(app, event)
             num1 = app.Temperature_enroulement_sEditField.Value;
            num2 = app.Section_fil_sEditField.Value;
            num3 = app.Nombre_paires_de_poles_sEditField.Value;
            num4 = app.Nombre_de_tours_par_bobines_sEditField.Value;
            num5 = app.qNbdencochespolephaseEditField.Value;
            num6 = app.hauteur_tete_bobineEditField.Value;
            num7=app.Diametre_interne_sEditField.Value;
            num8=app.Profondeur_moteurEditField.Value;
            num9=app.N_bobines_par_encochesEditField.Value;

            num10=app.Temperature_enroulement_rEditField.Value;
            num11=app.Section_fil_rEditField.Value;
            num12=app.Nombre_paires_de_poles_rEditField.Value;
            num13=app.Nombre_de_tours_par_bobines_rEditField.Value;
            num14=app.q_rNbdencochespolephaseEditField.Value;
            num15=app.hauteur_tete_bobine_rEditField.Value;
            num16=app.Diametre_externe_rEditField.Value;
            num17=app.Longueur_rotorEditField.Value;
            num18=app.N_bobines_par_encoches_rEditField.Value;
            num19=app.Nombre_phasesEditField.Value;
            num20=app.angle_de_racourcissementEditField.Value;
            num21=app.fEditField.Value;
            num22=app.BeEditField.Value;
            num23=app.Rayon_machineEditField.Value;
            num24=app.Longueur_dent_rotorEditField.Value;
            num25=app.Largeur_dent_rotorEditField.Value;
            num26=app.Longueur_dent_statorEditField.Value;
            num27=app.Largeur_dent_statorEditField.Value;
            num28=app.Largeur_encoche_sEditField.Value;
            num29=app.Largeur_encoche_sEditField.Value;
            num30=app.GlissementEditField.Value;
            u=[num1,num2,num3,num4,num5,num6,num7,num8,num9,num10,num11,num12,num13,num14,num15,num16,num17,num18,num19,num20,num21,num22,num23,num24,num25,num26,num27,num28,num29,num30];
                        result = cosphi(u);
            formattedResult = sprintf('%.4e', result);
            app.CosPhiEditField.Value = formattedResult;
        end

        % Button pushed function: CalculerButton_12
        function CalculerButton_12Pushed(app, event)
            num1 = app.Temperature_enroulement_sEditField.Value;
            num2 = app.Section_fil_sEditField.Value;
            num3 = app.Nombre_paires_de_poles_sEditField.Value;
            num4 = app.Nombre_de_tours_par_bobines_sEditField.Value;
            num5 = app.qNbdencochespolephaseEditField.Value;
            num6 = app.hauteur_tete_bobineEditField.Value;
            num7=app.Diametre_interne_sEditField.Value;
            num8=app.Profondeur_moteurEditField.Value;
            num9=app.N_bobines_par_encochesEditField.Value;

            num10=app.Temperature_enroulement_rEditField.Value;
            num11=app.Section_fil_rEditField.Value;
            num12=app.Nombre_paires_de_poles_rEditField.Value;
            num13=app.Nombre_de_tours_par_bobines_rEditField.Value;
            num14=app.q_rNbdencochespolephaseEditField.Value;
            num15=app.hauteur_tete_bobine_rEditField.Value;
            num16=app.Diametre_externe_rEditField.Value;
            num17=app.Longueur_rotorEditField.Value;
            num18=app.N_bobines_par_encoches_rEditField.Value;
            num19=app.Nombre_phasesEditField.Value;
            num20=app.angle_de_racourcissementEditField.Value;
            num21=app.fEditField.Value;
            num22=app.BeEditField.Value;
            num23=app.Rayon_machineEditField.Value;
            num24=app.Longueur_dent_rotorEditField.Value;
            num25=app.Largeur_dent_rotorEditField.Value;
            num26=app.Longueur_dent_statorEditField.Value;
            num27=app.Largeur_dent_statorEditField.Value;
            num28=app.Largeur_encoche_sEditField.Value;
            num29=app.Largeur_encoche_sEditField.Value;
            num30=app.GlissementEditField.Value;
            u=[num1,num2,num3,num4,num5,num6,num7,num8,num9,num10,num11,num12,num13,num14,num15,num16,num17,num18,num19,num20,num21,num22,num23,num24,num25,num26,num27,num28,num29,num30];
            result = abs(Vs(u));
            formattedResult = sprintf('%.4e', result);
            app.Vs_effEditField.Value = formattedResult;
        end

        % Button pushed function: CalculerButton_13
        function CalculerButton_13Pushed(app, event)
                        num1 = app.Temperature_enroulement_sEditField.Value;
            num2 = app.Section_fil_sEditField.Value;
            num3 = app.Nombre_paires_de_poles_sEditField.Value;
            num4 = app.Nombre_de_tours_par_bobines_sEditField.Value;
            num5 = app.qNbdencochespolephaseEditField.Value;
            num6 = app.hauteur_tete_bobineEditField.Value;
            num7=app.Diametre_interne_sEditField.Value;
            num8=app.Profondeur_moteurEditField.Value;
            num9=app.N_bobines_par_encochesEditField.Value;

            num10=app.Temperature_enroulement_rEditField.Value;
            num11=app.Section_fil_rEditField.Value;
            num12=app.Nombre_paires_de_poles_rEditField.Value;
            num13=app.Nombre_de_tours_par_bobines_rEditField.Value;
            num14=app.q_rNbdencochespolephaseEditField.Value;
            num15=app.hauteur_tete_bobine_rEditField.Value;
            num16=app.Diametre_externe_rEditField.Value;
            num17=app.Longueur_rotorEditField.Value;
            num18=app.N_bobines_par_encoches_rEditField.Value;
            num19=app.Nombre_phasesEditField.Value;
            num20=app.angle_de_racourcissementEditField.Value;
            num21=app.fEditField.Value;
            num22=app.BeEditField.Value;
            num23=app.Rayon_machineEditField.Value;
            num24=app.Longueur_dent_rotorEditField.Value;
            num25=app.Largeur_dent_rotorEditField.Value;
            num26=app.Longueur_dent_statorEditField.Value;
            num27=app.Largeur_dent_statorEditField.Value;
            num28=app.Largeur_encoche_sEditField.Value;
            num29=app.Largeur_encoche_sEditField.Value;
            num30=app.GlissementEditField.Value;
            u=[num1,num2,num3,num4,num5,num6,num7,num8,num9,num10,num11,num12,num13,num14,num15,num16,num17,num18,num19,num20,num21,num22,num23,num24,num25,num26,num27,num28,num29,num30];
                        result = Is(u);
            formattedResult = sprintf('%.4e', result);
            app.IsEditField.Value = formattedResult;
        end

        % Button pushed function: CalculerButton_19
        function CalculerButton_14Pushed(app, event)
            num1 = app.Temperature_enroulement_sEditField.Value;
            num2 = app.Section_fil_sEditField.Value;
            num3 = app.Nombre_paires_de_poles_sEditField.Value;
            num4 = app.Nombre_de_tours_par_bobines_sEditField.Value;
            num5 = app.qNbdencochespolephaseEditField.Value;
            num6 = app.hauteur_tete_bobineEditField.Value;
            num7=app.Diametre_interne_sEditField.Value;
            num8=app.Profondeur_moteurEditField.Value;
            num9=app.N_bobines_par_encochesEditField.Value;

            num10=app.Temperature_enroulement_rEditField.Value;
            num11=app.Section_fil_rEditField.Value;
            num12=app.Nombre_paires_de_poles_rEditField.Value;
            num13=app.Nombre_de_tours_par_bobines_rEditField.Value;
            num14=app.q_rNbdencochespolephaseEditField.Value;
            num15=app.hauteur_tete_bobine_rEditField.Value;
            num16=app.Diametre_externe_rEditField.Value;
            num17=app.Longueur_rotorEditField.Value;
            num18=app.N_bobines_par_encoches_rEditField.Value;
            num19=app.Nombre_phasesEditField.Value;
            num20=app.angle_de_racourcissementEditField.Value;
            num21=app.fEditField.Value;
            num22=app.BeEditField.Value;
            num23=app.Rayon_machineEditField.Value;
            num24=app.Longueur_dent_rotorEditField.Value;
            num25=app.Largeur_dent_rotorEditField.Value;
            num26=app.Longueur_dent_statorEditField.Value;
            num27=app.Largeur_dent_statorEditField.Value;
            num28=app.Largeur_encoche_sEditField.Value;
            num29=app.Largeur_encoche_sEditField.Value;
            num30=app.GlissementEditField.Value;
            u=[num1,num2,num3,num4,num5,num6,num7,num8,num9,num10,num11,num12,num13,num14,num15,num16,num17,num18,num19,num20,num21,num22,num23,num24,num25,num26,num27,num28,num29,num30];
                        result = Puissance_meca(u);
            formattedResult = sprintf('%.4e', result);
            app.Puissance_mecaEditField.Value = formattedResult;
        end

        % Button pushed function: CalculerButton_15
        function CalculerButton_15Pushed(app, event)
            num1 = app.Temperature_enroulement_sEditField.Value;
            num2 = app.Section_fil_sEditField.Value;
            num3 = app.Nombre_paires_de_poles_sEditField.Value;
            num4 = app.Nombre_de_tours_par_bobines_sEditField.Value;
            num5 = app.qNbdencochespolephaseEditField.Value;
            num6 = app.hauteur_tete_bobineEditField.Value;
            num7=app.Diametre_interne_sEditField.Value;
            num8=app.Profondeur_moteurEditField.Value;
            num9=app.N_bobines_par_encochesEditField.Value;

            num10=app.Temperature_enroulement_rEditField.Value;
            num11=app.Section_fil_rEditField.Value;
            num12=app.Nombre_paires_de_poles_rEditField.Value;
            num13=app.Nombre_de_tours_par_bobines_rEditField.Value;
            num14=app.q_rNbdencochespolephaseEditField.Value;
            num15=app.hauteur_tete_bobine_rEditField.Value;
            num16=app.Diametre_externe_rEditField.Value;
            num17=app.Longueur_rotorEditField.Value;
            num18=app.N_bobines_par_encoches_rEditField.Value;
            num19=app.Nombre_phasesEditField.Value;
            num20=app.angle_de_racourcissementEditField.Value;
            num21=app.fEditField.Value;
            num22=app.BeEditField.Value;
            num23=app.Rayon_machineEditField.Value;
            num24=app.Longueur_dent_rotorEditField.Value;
            num25=app.Largeur_dent_rotorEditField.Value;
            num26=app.Longueur_dent_statorEditField.Value;
            num27=app.Largeur_dent_statorEditField.Value;
            num28=app.Largeur_encoche_sEditField.Value;
            num29=app.Largeur_encoche_sEditField.Value;
            num30=app.GlissementEditField.Value;
            u=[num1,num2,num3,num4,num5,num6,num7,num8,num9,num10,num11,num12,num13,num14,num15,num16,num17,num18,num19,num20,num21,num22,num23,num24,num25,num26,num27,num28,num29,num30];
                        result = Im(u);
            formattedResult = sprintf('%.4e', result);
            app.ImEditField.Value = formattedResult;
        end

        % Button pushed function: TracerButton
        function TracerButtonPushed(app, event)
            num1 = app.Temperature_enroulement_sEditField.Value;
            num2 = app.Section_fil_sEditField.Value;
            num3 = app.Nombre_paires_de_poles_sEditField.Value;
            num4 = app.Nombre_de_tours_par_bobines_sEditField.Value;
            num5 = app.qNbdencochespolephaseEditField.Value;
            num6 = app.hauteur_tete_bobineEditField.Value;
            num7=app.Diametre_interne_sEditField.Value;
            num8=app.Profondeur_moteurEditField.Value;
            num9=app.N_bobines_par_encochesEditField.Value;

            num10=app.Temperature_enroulement_rEditField.Value;
            num11=app.Section_fil_rEditField.Value;
            num12=app.Nombre_paires_de_poles_rEditField.Value;
            num13=app.Nombre_de_tours_par_bobines_rEditField.Value;
            num14=app.q_rNbdencochespolephaseEditField.Value;
            num15=app.hauteur_tete_bobine_rEditField.Value;
            num16=app.Diametre_externe_rEditField.Value;
            num17=app.Longueur_rotorEditField.Value;
            num18=app.N_bobines_par_encoches_rEditField.Value;
            num19=app.Nombre_phasesEditField.Value;
            num20=app.angle_de_racourcissementEditField.Value;
            num21=app.fEditField.Value;
            num22=app.BeEditField.Value;
            num23=app.Rayon_machineEditField.Value;
            num24=app.Longueur_dent_rotorEditField.Value;
            num25=app.Largeur_dent_rotorEditField.Value;
            num26=app.Longueur_dent_statorEditField.Value;
            num27=app.Largeur_dent_statorEditField.Value;
            num28=app.Largeur_encoche_sEditField.Value;
            num29=app.Largeur_encoche_sEditField.Value;
            num30=app.GlissementEditField.Value;
            u=[num1,num2,num3,num4,num5,num6,num7,num8,num9,num10,num11,num12,num13,num14,num15,num16,num17,num18,num19,num20,num21,num22,num23,num24,num25,num26,num27,num28,num29,num30];
            
            mat=trace_couple_glissement(u);
            plot(app.UIAxes, mat(1, :), mat(2, :));
        end

        % Button pushed function: EffacerButton
        function EffacerButtonPushed(app, event)
            cla(app.UIAxes)
        end

        % Button pushed function: ChargerButton
        function ChargerButtonPushed(app, event)
            u=create_u_test();
            app.Temperature_enroulement_sEditField.Value = u(1);
            app.Section_fil_sEditField.Value = u(2);
            app.Nombre_paires_de_poles_sEditField.Value = u(3);
            app.Nombre_de_tours_par_bobines_sEditField.Value = u(4);
            app.qNbdencochespolephaseEditField.Value = u(5);
            app.hauteur_tete_bobineEditField.Value = u(6);
            app.Diametre_interne_sEditField.Value = u(7);
            app.Profondeur_moteurEditField.Value = u(8);
            app.N_bobines_par_encochesEditField.Value = u(9);
            app.Temperature_enroulement_rEditField.Value = u(10);
            app.Section_fil_rEditField.Value = u(11);
            app.Nombre_paires_de_poles_rEditField.Value = u(12);
            app.Nombre_de_tours_par_bobines_rEditField.Value = u(13);
            app.q_rNbdencochespolephaseEditField.Value = u(14);
            app.hauteur_tete_bobine_rEditField.Value = u(15);
            app.Diametre_externe_rEditField.Value = u(16);
            app.Longueur_rotorEditField.Value = u(17);
            app.N_bobines_par_encoches_rEditField.Value = u(18);
            app.Nombre_phasesEditField.Value = u(19);
            app.angle_de_racourcissementEditField.Value = u(20);
            app.fEditField.Value = u(21);
            app.BeEditField.Value = u(22);
            app.Rayon_machineEditField.Value = u(23);
            app.Longueur_dent_rotorEditField.Value = u(24);
            app.Largeur_dent_rotorEditField.Value = u(25);
            app.Longueur_dent_statorEditField.Value = u(26);
            app.Largeur_dent_statorEditField.Value = u(27);
            app.Largeur_encoche_sEditField.Value = u(28);
            app.Largeur_encoche_rEditField.Value = u(29);
            app.GlissementEditField.Value = u(30);
        end

        % Button pushed function: SauvegarderButton
        function SauvegarderButtonPushed(app, event)
            num1 = app.Temperature_enroulement_sEditField.Value;
            num2 = app.Section_fil_sEditField.Value;
            num3 = app.Nombre_paires_de_poles_sEditField.Value;
            num4 = app.Nombre_de_tours_par_bobines_sEditField.Value;
            num5 = app.qNbdencochespolephaseEditField.Value;
            num6 = app.hauteur_tete_bobineEditField.Value;
            num7=app.Diametre_interne_sEditField.Value;
            num8=app.Profondeur_moteurEditField.Value;
            num9=app.N_bobines_par_encochesEditField.Value;

            num10=app.Temperature_enroulement_rEditField.Value;
            num11=app.Section_fil_rEditField.Value;
            num12=app.Nombre_paires_de_poles_rEditField.Value;
            num13=app.Nombre_de_tours_par_bobines_rEditField.Value;
            num14=app.q_rNbdencochespolephaseEditField.Value;
            num15=app.hauteur_tete_bobine_rEditField.Value;
            num16=app.Diametre_externe_rEditField.Value;
            num17=app.Longueur_rotorEditField.Value;
            num18=app.N_bobines_par_encoches_rEditField.Value;
            num19=app.Nombre_phasesEditField.Value;
            num20=app.angle_de_racourcissementEditField.Value;
            num21=app.fEditField.Value;
            num22=app.BeEditField.Value;
            num23=app.Rayon_machineEditField.Value;
            num24=app.Longueur_dent_rotorEditField.Value;
            num25=app.Largeur_dent_rotorEditField.Value;
            num26=app.Longueur_dent_statorEditField.Value;
            num27=app.Largeur_dent_statorEditField.Value;
            num28=app.Largeur_encoche_sEditField.Value;
            num29=app.Largeur_encoche_sEditField.Value;
            num30=app.GlissementEditField.Value;
            u=[num1,num2,num3,num4,num5,num6,num7,num8,num9,num10,num11,num12,num13,num14,num15,num16,num17,num18,num19,num20,num21,num22,num23,num24,num25,num26,num27,num28,num29,num30];
            sauvegarderParametres(u);
        end

        % Button pushed function: CalculerButton_20
        function CalculerButton_20Pushed(app, event)
            num1 = app.Temperature_enroulement_sEditField.Value;
            num2 = app.Section_fil_sEditField.Value;
            num3 = app.Nombre_paires_de_poles_sEditField.Value;
            num4 = app.Nombre_de_tours_par_bobines_sEditField.Value;
            num5 = app.qNbdencochespolephaseEditField.Value;
            num6 = app.hauteur_tete_bobineEditField.Value;
            num7=app.Diametre_interne_sEditField.Value;
            num8=app.Profondeur_moteurEditField.Value;
            num9=app.N_bobines_par_encochesEditField.Value;

            num10=app.Temperature_enroulement_rEditField.Value;
            num11=app.Section_fil_rEditField.Value;
            num12=app.Nombre_paires_de_poles_rEditField.Value;
            num13=app.Nombre_de_tours_par_bobines_rEditField.Value;
            num14=app.q_rNbdencochespolephaseEditField.Value;
            num15=app.hauteur_tete_bobine_rEditField.Value;
            num16=app.Diametre_externe_rEditField.Value;
            num17=app.Longueur_rotorEditField.Value;
            num18=app.N_bobines_par_encoches_rEditField.Value;
            num19=app.Nombre_phasesEditField.Value;
            num20=app.angle_de_racourcissementEditField.Value;
            num21=app.fEditField.Value;
            num22=app.BeEditField.Value;
            num23=app.Rayon_machineEditField.Value;
            num24=app.Longueur_dent_rotorEditField.Value;
            num25=app.Largeur_dent_rotorEditField.Value;
            num26=app.Longueur_dent_statorEditField.Value;
            num27=app.Largeur_dent_statorEditField.Value;
            num28=app.Largeur_encoche_sEditField.Value;
            num29=app.Largeur_encoche_sEditField.Value;
            num30=app.GlissementEditField.Value;
            u=[num1,num2,num3,num4,num5,num6,num7,num8,num9,num10,num11,num12,num13,num14,num15,num16,num17,num18,num19,num20,num21,num22,num23,num24,num25,num26,num27,num28,num29,num30];
                        result = Rm(u);
            formattedResult = sprintf('%.4e', result);
            app.RmEditField.Value = formattedResult;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Glissement')
            ylabel(app.UIAxes, 'Couple')
            zlabel(app.UIAxes, 'Z')
            app.CalculerButton = uibutton(app.UIFigure, 'push');
            app.CalculerButton.ButtonPushedFcn = createCallbackFcn(app, @CalculerButtonPushed, true);
            app.CalculerButton.Text = 'Calculer';
            app.CalculerButton_2 = uibutton(app.UIFigure, 'push');
            app.CalculerButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculerButton_2Pushed, true);
            app.CalculerButton_2.Text = 'Calculer';
            app.CalculerButton_3 = uibutton(app.UIFigure, 'push');
            app.CalculerButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculerButton_3Pushed, true);
            app.CalculerButton_3.Text = 'Calculer';
            app.CalculerButton_4 = uibutton(app.UIFigure, 'push');
            app.CalculerButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalculerButton_4Pushed, true);
            app.CalculerButton_4.Text = 'Calculer';
            app.CalculerButton_5 = uibutton(app.UIFigure, 'push');
            app.CalculerButton_5.ButtonPushedFcn = createCallbackFcn(app, @CalculerButton_5Pushed, true);
            app.CalculerButton_5.Text = 'Calculer';
            app.CalculerButton_7 = uibutton(app.UIFigure, 'push');
            app.CalculerButton_7.ButtonPushedFcn = createCallbackFcn(app, @CalculerButton_7Pushed, true);
            app.CalculerButton_7.Text = 'Calculer';
            app.ToutcalculerButton = uibutton(app.UIFigure, 'push');
            app.ToutcalculerButton.ButtonPushedFcn = createCallbackFcn(app, @ToutcalculerButtonPushed, true);
            app.ToutcalculerButton.Text = 'Tout calculer';
            app.Nombre_phasesEditFieldLabel = uilabel(app.UIFigure);
            app.Nombre_phasesEditFieldLabel.Text = 'Nombre_phases';
            app.Nombre_phasesEditField = uieditfield(app.UIFigure, 'numeric');
            app.angle_de_racourcissementEditFieldLabel = uilabel(app.UIFigure);
            app.angle_de_racourcissementEditFieldLabel.Text = 'angle_de_racourcissement';
            app.angle_de_racourcissementEditField = uieditfield(app.UIFigure, 'numeric');
            app.fEditFieldLabel = uilabel(app.UIFigure);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.UIFigure, 'numeric');
            app.BeEditFieldLabel = uilabel(app.UIFigure);
            app.BeEditFieldLabel.Text = 'Be';
            app.BeEditField = uieditfield(app.UIFigure, 'numeric');
            app.Rayon_machineEditFieldLabel = uilabel(app.UIFigure);
            app.Rayon_machineEditFieldLabel.Text = 'Rayon_machine';
            app.Rayon_machineEditField = uieditfield(app.UIFigure, 'numeric');
            app.Largeur_dent_rotorEditFieldLabel = uilabel(app.UIFigure);
            app.Largeur_dent_rotorEditFieldLabel.Text = 'Largeur_dent_rotor';
            app.Largeur_dent_rotorEditField = uieditfield(app.UIFigure, 'numeric');
            app.Longueur_dent_statorEditFieldLabel = uilabel(app.UIFigure);
            app.Longueur_dent_statorEditFieldLabel.Text = 'Longueur_dent_stator';
            app.Longueur_dent_statorEditField = uieditfield(app.UIFigure, 'numeric');
            app.Largeur_dent_statorEditFieldLabel = uilabel(app.UIFigure);
            app.Largeur_dent_statorEditFieldLabel.Text = 'Largeur_dent_stator';
            app.Largeur_dent_statorEditField = uieditfield(app.UIFigure, 'numeric');
            app.Longueur_dent_rotorEditFieldLabel = uilabel(app.UIFigure);
            app.Longueur_dent_rotorEditFieldLabel.Text = 'Longueur_dent_rotor';
            app.Longueur_dent_rotorEditField = uieditfield(app.UIFigure, 'numeric');
            app.Temperature_enroulement_sEditFieldLabel = uilabel(app.UIFigure);
            app.Temperature_enroulement_sEditFieldLabel.Text = 'Temperature_enroulement_s';
            app.Temperature_enroulement_sEditField = uieditfield(app.UIFigure, 'numeric');
            app.Section_fil_sEditFieldLabel = uilabel(app.UIFigure);
            app.Section_fil_sEditFieldLabel.Text = 'Section_fil_s';
            app.Section_fil_sEditField = uieditfield(app.UIFigure, 'numeric');
            app.Nombre_paires_de_poles_sEditFieldLabel = uilabel(app.UIFigure);
            app.Nombre_paires_de_poles_sEditFieldLabel.Text = 'Nombre_paires_de_poles_s';
            app.Nombre_paires_de_poles_sEditField = uieditfield(app.UIFigure, 'numeric');
            app.Nombre_de_tours_par_bobines_sEditFieldLabel = uilabel(app.UIFigure);
            app.Nombre_de_tours_par_bobines_sEditFieldLabel.Text = 'Nombre_de_tours_par_bobines_s';
            app.Nombre_de_tours_par_bobines_sEditField = uieditfield(app.UIFigure, 'numeric');
            app.qNbdencochespolephaseLabel = uilabel(app.UIFigure);
            app.qNbdencochespolephaseLabel.Text = 'q(Nb d''encoches /pole /phase)';
            app.qNbdencochespolephaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.Diametre_interne_sEditFieldLabel = uilabel(app.UIFigure);
            app.Diametre_interne_sEditFieldLabel.Text = 'Diametre_interne_s';
            app.Diametre_interne_sEditField = uieditfield(app.UIFigure, 'numeric');
            app.Profondeur_moteurEditFieldLabel = uilabel(app.UIFigure);
            app.Profondeur_moteurEditFieldLabel.Text = 'Profondeur_moteur';
            app.Profondeur_moteurEditField = uieditfield(app.UIFigure, 'numeric');
            app.N_bobines_par_encochesEditFieldLabel = uilabel(app.UIFigure);
            app.N_bobines_par_encochesEditFieldLabel.Text = 'N_bobines_par_encoches';
            app.N_bobines_par_encochesEditField = uieditfield(app.UIFigure, 'numeric');
            app.hauteur_tete_bobineEditFieldLabel = uilabel(app.UIFigure);
            app.hauteur_tete_bobineEditFieldLabel.Text = 'hauteur_tete_bobine';
            app.hauteur_tete_bobineEditField = uieditfield(app.UIFigure, 'numeric');
            app.RsEditFieldLabel = uilabel(app.UIFigure);
            app.RsEditFieldLabel.Text = 'Rs';
            app.RsEditField = uieditfield(app.UIFigure, 'text');
            app.Temperature_enroulement_rEditFieldLabel = uilabel(app.UIFigure);
            app.Temperature_enroulement_rEditFieldLabel.Text = 'Temperature_enroulement_r';
            app.Temperature_enroulement_rEditField = uieditfield(app.UIFigure, 'numeric');
            app.Section_fil_rEditFieldLabel = uilabel(app.UIFigure);
            app.Section_fil_rEditFieldLabel.Text = 'Section_fil_r';
            app.Section_fil_rEditField = uieditfield(app.UIFigure, 'numeric');
            app.Nombre_paires_de_poles_rEditFieldLabel = uilabel(app.UIFigure);
            app.Nombre_paires_de_poles_rEditFieldLabel.Text = 'Nombre_paires_de_poles_r';
            app.Nombre_paires_de_poles_rEditField = uieditfield(app.UIFigure, 'numeric');
            app.Nombre_de_tours_par_bobines_rEditFieldLabel = uilabel(app.UIFigure);
            app.Nombre_de_tours_par_bobines_rEditFieldLabel.Text = 'Nombre_de_tours_par_bobines_r';
            app.Nombre_de_tours_par_bobines_rEditField = uieditfield(app.UIFigure, 'numeric');
            app.q_rNbdencochespolephaseEditFieldLabel = uilabel(app.UIFigure);
            app.q_rNbdencochespolephaseEditFieldLabel.Text = 'q_r(Nb d''encoches /pole /phase)';
            app.q_rNbdencochespolephaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.Diametre_externe_rEditFieldLabel = uilabel(app.UIFigure);
            app.Diametre_externe_rEditFieldLabel.Text = 'Diametre_externe_r';
            app.Diametre_externe_rEditField = uieditfield(app.UIFigure, 'numeric');
            app.Longueur_rotorEditFieldLabel = uilabel(app.UIFigure);
            app.Longueur_rotorEditFieldLabel.Text = 'Longueur_rotor';
            app.Longueur_rotorEditField = uieditfield(app.UIFigure, 'numeric');
            app.N_bobines_par_encoches_rEditFieldLabel = uilabel(app.UIFigure);
            app.N_bobines_par_encoches_rEditFieldLabel.Text = 'N_bobines_par_encoches_r';
            app.N_bobines_par_encoches_rEditField = uieditfield(app.UIFigure, 'numeric');
            app.hauteur_tete_bobine_rEditFieldLabel = uilabel(app.UIFigure);
            app.hauteur_tete_bobine_rEditFieldLabel.Text = 'hauteur_tete_bobine_r';
            app.hauteur_tete_bobine_rEditField = uieditfield(app.UIFigure, 'numeric');
            app.RrEditFieldLabel = uilabel(app.UIFigure);
            app.RrEditFieldLabel.Text = 'Rr';
            app.RrEditField = uieditfield(app.UIFigure, 'text');
            app.XmEditFieldLabel = uilabel(app.UIFigure);
            app.XmEditFieldLabel.Text = 'Xm';
            app.XmEditField = uieditfield(app.UIFigure, 'text');
            app.XsEditFieldLabel = uilabel(app.UIFigure);
            app.XsEditFieldLabel.Text = 'Xs';
            app.XsEditField = uieditfield(app.UIFigure, 'text');
            app.XrEditFieldLabel = uilabel(app.UIFigure);
            app.XrEditFieldLabel.Text = 'Xr';
            app.XrEditField = uieditfield(app.UIFigure, 'text');
            app.Largeur_encoche_sEditFieldLabel = uilabel(app.UIFigure);
            app.Largeur_encoche_sEditFieldLabel.Text = 'Largeur_encoche_s';
            app.Largeur_encoche_sEditField = uieditfield(app.UIFigure, 'numeric');
            app.Largeur_encoche_rEditFieldLabel = uilabel(app.UIFigure);
            app.Largeur_encoche_rEditFieldLabel.Text = 'Largeur_encoche_r';
            app.Largeur_encoche_rEditField = uieditfield(app.UIFigure, 'numeric');
            app.Pertes_ferEditFieldLabel = uilabel(app.UIFigure);
            app.Pertes_ferEditFieldLabel.Text = 'Pertes_fer';
            app.Pertes_ferEditField = uieditfield(app.UIFigure, 'text');
            app.KLabel = uilabel(app.UIFigure);
            app.KLabel.Text = '(K)';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = '(m²)';
            app.KLabel_2 = uilabel(app.UIFigure);
            app.KLabel_2.Text = '(K)';
            app.mLabel_2 = uilabel(app.UIFigure);
            app.mLabel_2.Text = '(m²)';
            app.mLabel_3 = uilabel(app.UIFigure);
            app.mLabel_3.Text = '(m)';
            app.mLabel_4 = uilabel(app.UIFigure);
            app.mLabel_4.Text = '(m)';
            app.mLabel_5 = uilabel(app.UIFigure);
            app.mLabel_5.Text = '(m)';
            app.mLabel_6 = uilabel(app.UIFigure);
            app.mLabel_6.Text = '(m)';
            app.mLabel_7 = uilabel(app.UIFigure);
            app.mLabel_7.Text = '(m)';
            app.mLabel_8 = uilabel(app.UIFigure);
            app.mLabel_8.Text = '(m)';
            app.mLabel_9 = uilabel(app.UIFigure);
            app.mLabel_9.Text = '(m)';
            app.mLabel_10 = uilabel(app.UIFigure);
            app.mLabel_10.Text = '(m)';
            app.mLabel_11 = uilabel(app.UIFigure);
            app.mLabel_11.Text = '(m)';
            app.mLabel_12 = uilabel(app.UIFigure);
            app.mLabel_12.Text = '(m)';
            app.mLabel_13 = uilabel(app.UIFigure);
            app.mLabel_13.Text = '(m)';
            app.TLabel = uilabel(app.UIFigure);
            app.TLabel.Text = '(T)';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = '(Hz)';
            app.mLabel_14 = uilabel(app.UIFigure);
            app.mLabel_14.Text = '(m)';
            app.mLabel_15 = uilabel(app.UIFigure);
            app.mLabel_15.Text = '(m)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '(Ω)';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '(Ω)';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '(Ω)';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '(Ω)';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '(Ω)';
            app.WLabel_2 = uilabel(app.UIFigure);
            app.WLabel_2.Text = '(W)';
            app.ErreursEditFieldLabel = uilabel(app.UIFigure);
            app.ErreursEditFieldLabel.Text = 'Erreurs';
            app.ErreursEditField = uieditfield(app.UIFigure, 'text');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'schema_equivalent__mas.png');
            app.GlissementEditFieldLabel = uilabel(app.UIFigure);
            app.GlissementEditFieldLabel.Text = 'Glissement';
            app.GlissementEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculerButton_10 = uibutton(app.UIFigure, 'push');
            app.CalculerButton_10.ButtonPushedFcn = createCallbackFcn(app, @CalculerButton_10Pushed, true);
            app.CalculerButton_10.Text = 'Calculer';
            app.EEditFieldLabel = uilabel(app.UIFigure);
            app.EEditFieldLabel.Text = 'E';
            app.EEditField = uieditfield(app.UIFigure, 'text');
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = '(V)';
            app.VLabel_2 = uilabel(app.UIFigure);
            app.VLabel_2.Text = '(V)';
            app.CalculerButton_12 = uibutton(app.UIFigure, 'push');
            app.CalculerButton_12.ButtonPushedFcn = createCallbackFcn(app, @CalculerButton_12Pushed, true);
            app.CalculerButton_12.Text = 'Calculer';
            app.Vs_effEditFieldLabel = uilabel(app.UIFigure);
            app.Vs_effEditFieldLabel.Text = 'Vs_eff';
            app.Vs_effEditField = uieditfield(app.UIFigure, 'text');
            app.ALabel = uilabel(app.UIFigure);
            app.ALabel.Text = '(A)';
            app.CalculerButton_13 = uibutton(app.UIFigure, 'push');
            app.CalculerButton_13.ButtonPushedFcn = createCallbackFcn(app, @CalculerButton_13Pushed, true);
            app.CalculerButton_13.Text = 'Calculer';
            app.IsEditFieldLabel = uilabel(app.UIFigure);
            app.IsEditFieldLabel.Text = 'Is';
            app.IsEditField = uieditfield(app.UIFigure, 'text');
            app.CalculerButton_15 = uibutton(app.UIFigure, 'push');
            app.CalculerButton_15.ButtonPushedFcn = createCallbackFcn(app, @CalculerButton_15Pushed, true);
            app.CalculerButton_15.Text = 'Calculer';
            app.ALabel_2 = uilabel(app.UIFigure);
            app.ALabel_2.Text = '(A)';
            app.ImEditFieldLabel = uilabel(app.UIFigure);
            app.ImEditFieldLabel.Text = 'Im';
            app.ImEditField = uieditfield(app.UIFigure, 'text');
            app.TracerButton = uibutton(app.UIFigure, 'push');
            app.TracerButton.ButtonPushedFcn = createCallbackFcn(app, @TracerButtonPushed, true);
            app.TracerButton.Text = 'Tracer';
            app.EffacerButton = uibutton(app.UIFigure, 'push');
            app.EffacerButton.ButtonPushedFcn = createCallbackFcn(app, @EffacerButtonPushed, true);
            app.EffacerButton.Text = 'Effacer';
            app.TLabel_2 = uilabel(app.UIFigure);
            app.TLabel_2.Text = '(T)';
            app.BdsEditFieldLabel = uilabel(app.UIFigure);
            app.BdsEditFieldLabel.Text = 'Bds';
            app.BdsEditField = uieditfield(app.UIFigure, 'text');
            app.TLabel_3 = uilabel(app.UIFigure);
            app.TLabel_3.Text = '(T)';
            app.BcsEditFieldLabel = uilabel(app.UIFigure);
            app.BcsEditFieldLabel.Text = 'Bcs';
            app.BcsEditField = uieditfield(app.UIFigure, 'text');
            app.ATmLabel = uilabel(app.UIFigure);
            app.ATmLabel.Text = '(AT/m)';
            app.ATmLabel_2 = uilabel(app.UIFigure);
            app.ATmLabel_2.Text = '(AT/m)';
            app.HdsEditFieldLabel = uilabel(app.UIFigure);
            app.HdsEditFieldLabel.Text = 'Hds';
            app.HdsEditField = uieditfield(app.UIFigure, 'text');
            app.HcsEditFieldLabel = uilabel(app.UIFigure);
            app.HcsEditFieldLabel.Text = 'Hcs';
            app.HcsEditField = uieditfield(app.UIFigure, 'text');
            app.ATmLabel_3 = uilabel(app.UIFigure);
            app.ATmLabel_3.Text = '(AT/m)';
            app.ATmLabel_4 = uilabel(app.UIFigure);
            app.ATmLabel_4.Text = '(AT/m)';
            app.HdrEditFieldLabel = uilabel(app.UIFigure);
            app.HdrEditFieldLabel.Text = 'Hdr';
            app.HdrEditField = uieditfield(app.UIFigure, 'text');
            app.HcrEditFieldLabel = uilabel(app.UIFigure);
            app.HcrEditFieldLabel.Text = 'Hcr';
            app.HcrEditField = uieditfield(app.UIFigure, 'text');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'B  (H).png');
            app.NmLabel = uilabel(app.UIFigure);
            app.NmLabel.Text = '(N.m)';
            app.CalculerButton_16 = uibutton(app.UIFigure, 'push');
            app.CalculerButton_16.ButtonPushedFcn = createCallbackFcn(app, @CalculerButton_8Pushed, true);
            app.CalculerButton_16.Text = 'Calculer';
            app.CalculerButton_17 = uibutton(app.UIFigure, 'push');
            app.CalculerButton_17.ButtonPushedFcn = createCallbackFcn(app, @CalculerButton_9Pushed, true);
            app.CalculerButton_17.Text = 'Calculer';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.CalculerButton_18 = uibutton(app.UIFigure, 'push');
            app.CalculerButton_18.ButtonPushedFcn = createCallbackFcn(app, @CalculerButton_11Pushed, true);
            app.CalculerButton_18.Text = 'Calculer';
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Text = '(W)';
            app.CalculerButton_19 = uibutton(app.UIFigure, 'push');
            app.CalculerButton_19.ButtonPushedFcn = createCallbackFcn(app, @CalculerButton_14Pushed, true);
            app.CalculerButton_19.Text = 'Calculer';
            app.CoupleEditFieldLabel = uilabel(app.UIFigure);
            app.CoupleEditFieldLabel.Text = 'Couple';
            app.CoupleEditField = uieditfield(app.UIFigure, 'text');
            app.RendementEditFieldLabel = uilabel(app.UIFigure);
            app.RendementEditFieldLabel.Text = 'Rendement';
            app.RendementEditField = uieditfield(app.UIFigure, 'text');
            app.CosPhiEditFieldLabel = uilabel(app.UIFigure);
            app.CosPhiEditFieldLabel.Text = 'Cos(Phi)';
            app.CosPhiEditField = uieditfield(app.UIFigure, 'text');
            app.Puissance_mecaEditFieldLabel = uilabel(app.UIFigure);
            app.Puissance_mecaEditFieldLabel.Text = 'Puissance_meca';
            app.Puissance_mecaEditField = uieditfield(app.UIFigure, 'text');
            app.RadLabel = uilabel(app.UIFigure);
            app.RadLabel.Text = '(Rad)';
            app.ChargerButton = uibutton(app.UIFigure, 'push');
            app.ChargerButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerButtonPushed, true);
            app.ChargerButton.Text = 'Charger';
            app.SauvegarderButton = uibutton(app.UIFigure, 'push');
            app.SauvegarderButton.ButtonPushedFcn = createCallbackFcn(app, @SauvegarderButtonPushed, true);
            app.SauvegarderButton.Text = 'Sauvegarder';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = {'Les paramètres géométriques peuvent :'; '- être entrés manuellement via les cases ci-dessus'; '- ou être chargés dans l''application en cliquant sur '; 'le bouton "Charger", qui utilisera les valeurs du fichier '; '"create_u_test.m" pour remplir les cases'; '- être sauvegardés dans "create_u_test.m" '; '(les valeurs contenues précédemment seront écrasées)'};
            app.TLabel_4 = uilabel(app.UIFigure);
            app.TLabel_4.Text = '(T)';
            app.TLabel_5 = uilabel(app.UIFigure);
            app.TLabel_5.Text = '(T)';
            app.BcrEditFieldLabel = uilabel(app.UIFigure);
            app.BcrEditFieldLabel.Text = 'Bcr';
            app.BcrEditField = uieditfield(app.UIFigure, 'text');
            app.BdrEditFieldLabel = uilabel(app.UIFigure);
            app.BdrEditFieldLabel.Text = 'Bdr';
            app.BdrEditField = uieditfield(app.UIFigure, 'text');
            app.CalculerButton_20 = uibutton(app.UIFigure, 'push');
            app.CalculerButton_20.ButtonPushedFcn = createCallbackFcn(app, @CalculerButton_20Pushed, true);
            app.CalculerButton_20.Text = 'Calculer';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '(Ω)';
            app.RmEditFieldLabel = uilabel(app.UIFigure);
            app.RmEditFieldLabel.Text = 'Rm';
            app.RmEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Dimensionnement_moteur

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end